classdef cnmf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CNMFintheLCSMSNINDSNIHLabel   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        loadimagesTab                 matlab.ui.container.Tab
        saveasaviButton               matlab.ui.control.Button
        bitsEditField                 matlab.ui.control.NumericEditField
        bitsEditFieldLabel            matlab.ui.control.Label
        maxEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        minEditFieldLabel             matlab.ui.control.Label
        adjustcontrastButton          matlab.ui.control.Button
        acceptROIButton               matlab.ui.control.Button
        previewButton                 matlab.ui.control.Button
        framesEditField               matlab.ui.control.EditField
        framesEditFieldLabel          matlab.ui.control.Label
        useallframesCheckBox          matlab.ui.control.CheckBox
        framerateEditField            matlab.ui.control.NumericEditField
        framerateEditFieldLabel       matlab.ui.control.Label
        acceptLoadButton              matlab.ui.control.Button
        clearLoadButton               matlab.ui.control.Button
        addROIButton                  matlab.ui.control.Button
        folderlocationEditField       matlab.ui.control.EditField
        folderlocationEditFieldLabel  matlab.ui.control.Label
        foldernameEditField           matlab.ui.control.EditField
        foldernameEditFieldLabel      matlab.ui.control.Label
        loadButton                    matlab.ui.control.Button
        UIAxesLoad                    matlab.ui.control.UIAxes
        initialconditionsTab          matlab.ui.container.Tab
        CNMFalgorithmTab              matlab.ui.container.Tab
        examineresultsTab             matlab.ui.container.Tab
        saveresultsTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        SelectedPath 
        nImages
        Frames 
        FramesField
        ImageData
        ROIpresent
        MeanImage
        ROInumber
        Mask
        ROI 
        
    end
    
    methods (Access = private)

        function [] = enableLoadButtons1(app)
            app.framerateEditField.Enable = true;
            app.framerateEditFieldLabel.Enable = true;
            app.useallframesCheckBox.Enable = true;
            app.framerateEditFieldLabel.Enable = true;
            app.framesEditField.Enable = false;
            app.framesEditFieldLabel.Enable = true;
            app.previewButton.Enable = true;
            app.clearLoadButton.Enable = true;
        end
        
        function [] = enableLoadButtons2(app,onoff)
            % onoff = true or false (boolean)
            app.saveasaviButton.Enable = onoff;
            app.acceptLoadButton.Enable = onoff;
            app.addROIButton.Enable = onoff;
            app.acceptROIButton.Enable = onoff;
            app.minEditField.Enable = onoff;
            app.minEditFieldLabel.Enable = onoff;
            app.maxEditField.Enable = onoff;
            app.maxEditFieldLabel.Enable = onoff;
            app.adjustcontrastButton.Enable = onoff;
        end
    end
    
    methods (Access = public)


        function [] = changelimits(app,src,~)
            contrastMax = str2num(src.Children(1).Children(3).Children.Children(2).Children.Children(2).Children(2).String); %#ok<*ST2NM>
            contrastMin = str2num(src.Children(1).Children(3).Children.Children(2).Children.Children(2).Children(5).String);
            maxIntensity = 2^app.bitsEditField.Value - 1;
            app.maxEditField.Value = round(contrastMax*maxIntensity);
            app.minEditField.Value = round(contrastMin*maxIntensity);
            img = app.MeanImage;
            img(img>=app.maxEditField.Value) = 0;
            img = img - app.minEditField.Value;
            img(img<0) = 0;
            img = img/(app.maxEditField.Value-app.minEditField.Value);
            img(img>1) = 0;
            imshow(img,'Parent',app.UIAxesLoad)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla(app.UIAxesLoad)
            app.ROIpresent = false;
            app.ROInumber = 0;
            app.ROI = [];
            app.ImageData = [];
            enableLoadButtons2(app,false)
            app.previewButton.Enable = false;
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            newPath = uigetdir(userpath,'Choose a directory');
            if newPath
                app.SelectedPath = newPath;
                foo = strfind(app.SelectedPath,filesep);
                folderName = app.SelectedPath(foo(end)+1:end);
                locationName = app.SelectedPath(1:foo(end)-1);
                app.foldernameEditField.Value = folderName;
                app.folderlocationEditField.Value = locationName;
                app.useallframesCheckBox.Value = 1;
                boo = dir([newPath,filesep,'*.TIF']);
                app.nImages = numel(boo);
                app.framesEditField.Value = ['1:',num2str(app.nImages)];
                app.FramesField = app.framesEditField.Value;
                app.Frames = 1:app.nImages;
                enableLoadButtons1(app)
                app.ROIpresent = false;
                app.ROInumber = 0;
                boo = dir([app.SelectedPath,filesep,'*.TIF']); % a list of all the files ending with the suffix .TIF
                imageInfo = imfinfo([app.SelectedPath,filesep,boo(1).name],'TIF');
                app.bitsEditField.Value = imageInfo.BitsPerSample;
                app.bitsEditField.Enable = true;
                app.bitsEditFieldLabel.Enable = true;
                enableLoadButtons2(app,false)
                app.previewButton.Enable = true;
            end
        end

        % Value changed function: framesEditField
        function framesEditFieldValueChanged(app, event)
            value = app.framesEditField.Value;
            frames = str2num(value);  
            frames = sort(frames); 
            frames = round(frames);
            if frames(1)<1 || frames(end)>app.nImages
                errordlg(['Frames must be between 1 and ',num2str(app.nImages)],'Check frames field'); 
                app.framesEditField.Value = app.FramesField;
                return
            end
            app.FramesField = app.framesEditField.Value;
            app.Frames = frames;
        end

        % Value changed function: useallframesCheckBox
        function useallframesCheckBoxValueChanged(app, event)
            value = app.useallframesCheckBox.Value;
            if value
                app.framesEditField.Value = ['1:',num2str(app.nImages)];
                app.FramesField = app.framesEditField.Value;
                app.Frames = 1:app.nImages;
                app.framesEditField.Enable = false;
            else
                app.framesEditField.Enable = true;
            end
        end

        % Button pushed function: previewButton
        function previewButtonPushed(app, event)
            app.previewButton.Enable = false;
            drawnow
            
            foo = dir([app.SelectedPath,filesep,'*.TIF']); % a list of all the files ending with the suffix .TIF
                      
            % The files are in alphabetical order. Unfortunately, this means that the
            % first few files end in A1_t1, A1_t10, A1_t11, A1_t12, ... instead of
            % A1_t1, A1_t2, A1_t3, A1_t4, ...
            % That is, the files are in alphabetical order, which is not the same as
            % chronological order. We want chronological order.
            % The lines below put the 120 images in chronological order and deposit them
            % in a variable called img.
            trialNum = zeros(app.nImages,1);
            for ii = 1:app.nImages
                boo = foo(ii).name;
                bar = strfind(boo,'t'); bar = bar(end);
                trialNum(ii) = str2double(boo(bar+1:end-4));
            end
            [~,trialNumIdx] = sort(trialNum);

            nFrames = numel(app.Frames);
            baz = imread([app.SelectedPath,filesep,foo(1).name],'tif');
            vPixels = size(baz,1);
            hPixels = size(baz,2);   
            if app.bitsEditField.Value==8
                app.ImageData = zeros(vPixels,hPixels,nFrames,'uint8');
            else
                app.ImageData = zeros(vPixels,hPixels,nFrames,'uint16');
            end
            for jj = 1:nFrames
                idx = app.Frames(jj);
                app.ImageData(:,:,jj) = imread([app.SelectedPath,filesep,foo(trialNumIdx(idx)).name],'tif');
            end
            app.ImageData = double(app.ImageData);
            maxIntensity = 2^app.bitsEditField.Value - 1;
            app.MeanImage = mean(app.ImageData,3);
            imshow(app.MeanImage/maxIntensity,'Parent',app.UIAxesLoad)

            enableLoadButtons2(app,true)

 
        end

        % Button pushed function: clearLoadButton
        function clearLoadButtonPushed(app, event)
            enableLoadButtons2(app,false)
            app.previewButton.Enable = true;
            app.ROIpresent = false;
            app.Mask = [];
            app.minEditField.Value = 0;
            app.maxEditField.Value = 2^app.bitsEditField.Value - 1;
            img1 = 0 * app.ImageData(:,:,1);
            imshow(img1,'Parent',app.UIAxesLoad)
        end

        % Button pushed function: adjustcontrastButton
        function adjustcontrastButtonPushed(app, event)
            maxIntensity = 2^app.bitsEditField.Value - 1;
            img1 = app.MeanImage/maxIntensity;
            figure
            h = imshow(img1);
            htool = imcontrast(h);
            htool.WindowButtonUpFcn = @app.changelimits;
            
        end

        % Button pushed function: addROIButton
        function addROIButtonPushed(app, event)
            roi = drawpolygon(app.UIAxesLoad);
            app.ROInumber = app.ROInumber + 1;
            app.ROI{app.ROInumber} = roi;
            app.ROIpresent = true;
            app.acceptROIButton.Enable = true;
            
        end

        % Value changed function: maxEditField, minEditField
        function minEditFieldValueChanged(app, event)
            img = app.MeanImage;
            img(img<app.minEditField.Value) = 0;
            img(img>=app.maxEditField.Value) = 0;
            img = img - app.minEditField.Value;
            img(img<0) = 0;
            img = img/(app.maxEditField.Value-app.minEditField.Value);
            img(img>1) = 1;
            imshow(img,'Parent',app.UIAxesLoad)
        end

        % Button pushed function: acceptROIButton
        function acceptROIButtonPushed(app, event)
            mask = zeros(size(app.MeanImage));
            if app.ROIpresent
                for ii = 1:app.ROInumber
                    if ishandle(app.ROI{ii})
                        b = createMask(app.ROI{ii});
                        mask = mask + b;
                    end
                end
                mask(mask>0) = 1;
                mask(mask<=0) = 0;
            end
            app.Mask = mask;
            
            for ii = 1:size(app.ImageData,3)
                app.ImageData(:,:,ii) = app.ImageData(:,:,ii) .* app.Mask;
            end
            app.MeanImage = mean(app.ImageData,3);
            img = app.MeanImage;
            img(img<app.minEditField.Value) = 0;
            img(img>app.maxEditField.Value) = 0;
            img = img - app.minEditField.Value;
            img = img/ (app.maxEditField.Value-app.minEditField.Value);
            img(img<0) = 0;
            img(img>1) = 1;
            imshow(img,'Parent',app.UIAxesLoad)
        end

        % Button pushed function: acceptLoadButton
        function acceptLoadButtonPushed(app, event)
            enableLoadButtons2(app,false)
            app.saveasaviButton.Enable = true;
            r = any(app.MeanImage,1);
            r = find(r);
            c = any(app.MeanImage,2);
            c = find(c);
            app.ImageData = app.ImageData(c(1):c(end),:,:);
            app.ImageData = app.ImageData(:,r(1):r(end),:);
            app.ImageData(app.ImageData>app.maxEditField.Value) = 0;
            app.ImageData = app.ImageData - app.minEditField.Value;
            app.ImageData = app.ImageData/(app.maxEditField.Value - app.minEditField.Value);
            app.ImageData(app.ImageData<0) = 0;
            app.ImageData(app.ImageData>1) = 1;
            app.ImageData = single(app.ImageData);
            app.MeanImage = mean(app.ImageData,3);
            imshow(app.MeanImage,'Parent',app.UIAxesLoad)

        end

        % Button pushed function: saveasaviButton
        function saveasaviButtonPushed(app, event)

            fileName = [app.SelectedPath,filesep,'myFile.avi'];
            v = VideoWriter(fileName,'Uncompressed AVI');
            v.FrameRate = app.framerateEditField.Value;
            open(v)
            for ii = 1:size(app.ImageData,3)
                foo = app.ImageData(:,:,ii);
                writeVideo(v,foo)
            end
            close(v)
            app.saveasaviButton.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loadimagesTab = uitab(app.TabGroup);
            app.loadimagesTab.Title = 'load images';
            app.UIAxesLoad = uiaxes(app.loadimagesTab);
            app.UIAxesLoad.XTick = [];
            app.UIAxesLoad.YTick = [];
            app.loadButton = uibutton(app.loadimagesTab, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.foldernameEditFieldLabel = uilabel(app.loadimagesTab);
            app.foldernameEditFieldLabel.Text = 'folder name';
            app.foldernameEditField = uieditfield(app.loadimagesTab, 'text');
            app.foldernameEditField.Editable = 'off';
            app.folderlocationEditFieldLabel = uilabel(app.loadimagesTab);
            app.folderlocationEditFieldLabel.Text = 'folder location';
            app.folderlocationEditField = uieditfield(app.loadimagesTab, 'text');
            app.folderlocationEditField.Editable = 'off';
            app.addROIButton = uibutton(app.loadimagesTab, 'push');
            app.addROIButton.ButtonPushedFcn = createCallbackFcn(app, @addROIButtonPushed, true);
            app.addROIButton.Enable = 'off';
            app.addROIButton.Text = 'add ROI';
            app.clearLoadButton = uibutton(app.loadimagesTab, 'push');
            app.clearLoadButton.ButtonPushedFcn = createCallbackFcn(app, @clearLoadButtonPushed, true);
            app.clearLoadButton.Enable = 'off';
            app.clearLoadButton.Text = 'clear';
            app.acceptLoadButton = uibutton(app.loadimagesTab, 'push');
            app.acceptLoadButton.ButtonPushedFcn = createCallbackFcn(app, @acceptLoadButtonPushed, true);
            app.acceptLoadButton.Enable = 'off';
            app.acceptLoadButton.Text = 'accept';
            app.framerateEditFieldLabel = uilabel(app.loadimagesTab);
            app.framerateEditFieldLabel.Enable = 'off';
            app.framerateEditFieldLabel.Text = 'frame rate';
            app.framerateEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.framerateEditField.Limits = [1 10000];
            app.framerateEditField.RoundFractionalValues = 'on';
            app.framerateEditField.ValueDisplayFormat = '%.0f';
            app.framerateEditField.Enable = 'off';
            app.framerateEditField.Value = 3;
            app.useallframesCheckBox = uicheckbox(app.loadimagesTab);
            app.useallframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @useallframesCheckBoxValueChanged, true);
            app.useallframesCheckBox.Enable = 'off';
            app.useallframesCheckBox.Text = 'use all frames';
            app.useallframesCheckBox.Value = true;
            app.framesEditFieldLabel = uilabel(app.loadimagesTab);
            app.framesEditFieldLabel.Enable = 'off';
            app.framesEditFieldLabel.Text = 'frames';
            app.framesEditField = uieditfield(app.loadimagesTab, 'text');
            app.framesEditField.ValueChangedFcn = createCallbackFcn(app, @framesEditFieldValueChanged, true);
            app.framesEditField.Enable = 'off';
            app.previewButton = uibutton(app.loadimagesTab, 'push');
            app.previewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.previewButton.Enable = 'off';
            app.previewButton.Text = 'preview';
            app.acceptROIButton = uibutton(app.loadimagesTab, 'push');
            app.acceptROIButton.ButtonPushedFcn = createCallbackFcn(app, @acceptROIButtonPushed, true);
            app.acceptROIButton.Enable = 'off';
            app.acceptROIButton.Text = 'accept ROI';
            app.adjustcontrastButton = uibutton(app.loadimagesTab, 'push');
            app.adjustcontrastButton.ButtonPushedFcn = createCallbackFcn(app, @adjustcontrastButtonPushed, true);
            app.adjustcontrastButton.Enable = 'off';
            app.adjustcontrastButton.Text = 'adjust contrast';
            app.minEditFieldLabel = uilabel(app.loadimagesTab);
            app.minEditFieldLabel.Enable = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.minEditField.Limits = [0 65536];
            app.minEditField.RoundFractionalValues = 'on';
            app.minEditField.ValueDisplayFormat = '%.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.loadimagesTab);
            app.maxEditFieldLabel.Enable = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.maxEditField.Limits = [0 65536];
            app.maxEditField.RoundFractionalValues = 'on';
            app.maxEditField.ValueDisplayFormat = '%.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.maxEditField.Value = 65536;
            app.bitsEditFieldLabel = uilabel(app.loadimagesTab);
            app.bitsEditFieldLabel.Enable = 'off';
            app.bitsEditFieldLabel.Text = 'bits';
            app.bitsEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.bitsEditField.Limits = [2 128];
            app.bitsEditField.RoundFractionalValues = 'on';
            app.bitsEditField.ValueDisplayFormat = '%.0f';
            app.bitsEditField.Editable = 'off';
            app.bitsEditField.Enable = 'off';
            app.bitsEditField.Value = 16;
            app.saveasaviButton = uibutton(app.loadimagesTab, 'push');
            app.saveasaviButton.ButtonPushedFcn = createCallbackFcn(app, @saveasaviButtonPushed, true);
            app.saveasaviButton.Enable = 'off';
            app.saveasaviButton.Text = 'save as avi';
            app.initialconditionsTab = uitab(app.TabGroup);
            app.initialconditionsTab.Title = 'initial conditions';
            app.CNMFalgorithmTab = uitab(app.TabGroup);
            app.CNMFalgorithmTab.Title = 'CNMF algorithm';
            app.examineresultsTab = uitab(app.TabGroup);
            app.examineresultsTab.Title = 'examine results';
            app.saveresultsTab = uitab(app.TabGroup);
            app.saveresultsTab.Title = 'save results';
            app.CNMFintheLCSMSNINDSNIHLabel = uilabel(app.UIFigure);
            app.CNMFintheLCSMSNINDSNIHLabel.Text = 'CNMF in the LCSMS, NINDS, NIH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cnmf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end